classdef motion_bin_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        PlayButton                   matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        RestartButton                matlab.ui.control.Button
        TrialnoEditFieldLabel        matlab.ui.control.Label
        TrialnoEditField             matlab.ui.control.NumericEditField
        LoadButton                   matlab.ui.control.Button
        BinarizationthresholdsLabel  matlab.ui.control.Label
        ROIHEADEditFieldLabel        matlab.ui.control.Label
        ROIHEADEditField             matlab.ui.control.NumericEditField
        ROILEFTPAWEditFieldLabel     matlab.ui.control.Label
        ROILEFTPAWEditField          matlab.ui.control.NumericEditField
        ROIRIGHTPAWEditFieldLabel    matlab.ui.control.Label
        ROIRIGHTPAWEditField         matlab.ui.control.NumericEditField
        ROIBACKEditFieldLabel        matlab.ui.control.Label
        ROIBACKEditField             matlab.ui.control.NumericEditField
        SaveallthresholdsButton      matlab.ui.control.Button
        ChoosefolderButton           matlab.ui.control.Button
        ShowROIsCheckBox             matlab.ui.control.CheckBox
        MotionalgoDropDownLabel      matlab.ui.control.Label
        MotionalgoDropDown           matlab.ui.control.DropDown
    end


    properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        % CLASS FOR BINARIZING MOTION VALUES
        %
        % ADD README
        %
        % (1) "Context-dependent cortical integration of visual and somatosensory stimuli in behaving mice"
        %           Moritz Buchholz, Yaroslav Sych, Fritjof Helmchen, Asli Ayaz
        %


        % Defining class variables
        
        vid;    % struct for video metadata
        meta;  % struct for behavioral metadata
        slash;
        motion_plot; % For the motion plot, updated every frame


    end

    methods (Access = private)
    
        function results = showROI(app)
            % This function shows the ROI on the current image
            img = app.vid.img;
            for roi = 1:length(app.meta.UI.vid.ROI.inside) % For every ROI
                %roi_points = app.meta.UI.vid.ROI.polygon{roi}; % Retrieve points drawing polygon
                % Reshape and round, axis are still inverted.
                %roi_points = round(reshape(roi_points, [length(roi_points)/2,2]));
                %tmp = roi_points;
                %roi_points = [tmp(:,2) tmp(:,1)]; % switch columns
                
                roi_points = app.meta.UI.vid.ROI.inside{roi};
                for l = 1:length(roi_points) % For every point in ROI
                    if roi == 1
                        img(roi_points(l,1),roi_points(l,2),:) = [255 0 0]; % Mark it red
                    elseif roi == 2
                        img(roi_points(l,1),roi_points(l,2),:) = [0 255 0]; % Mark it green
                    elseif roi == 3
                        img(roi_points(l,1),roi_points(l,2),:) = [0 0 255]; % Mark it blue
                    elseif roi ==4
                        img(roi_points(l,1),roi_points(l,2),:) = [255 0 255]; % Mark it magenta
                    end 
                end
            end
            imshow(img,'parent',app.UIAxes)
            drawnow;
   
        end
        
    end


    methods (Access = private)

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
             % Allocation
            app.vid = struct();
            app.slash = '/' ; % CHANGE THIS TO '/' ON MAC AND '\' ON WINDOWS
    
            % Open a folder
            app.vid.foldername = uigetdir();
            
            hold(app.UIAxes_2); % Plot is hold

        end

        % Value changed function: TrialnoEditField
        function TrialnoEditFieldValueChanged(app, event)
            value = app.TrialnoEditField.Value;
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Video is retrieved and displayed
            trial = app.TrialnoEditField.Value;
            fullpath = strcat(app.vid.foldername,app.slash,'Trial_',num2str(trial),'.mp4');
            app.vid.data = VideoReader(fullpath);
            app.vid.num_frames = app.vid.data.Duration * app.vid.data.FrameRate;
            app.vid.frame = 1; % points to the current index of the video
            app.vid.img = read(app.vid.data,1);
            imshow(app.vid.img,'parent',app.UIAxes);
            
            %% Next, the motion analysis metadata is retrieved
            slashes = strfind(app.vid.foldername,app.slash);
            
            app.meta = struct();
            app.meta = load(strcat(app.vid.foldername(1:slashes(end)),'motion_analysis.mat'));
            app.meta.UI = load(strcat(app.vid.foldername(1:slashes(end)),'UI_metadata.mat'));  
           
            % Now the motion analysis vectors are shown
            ma = app.MotionalgoDropDown.Value; % retrieve motion method from GUI
            % check it is valid:
            valid = 0;
            for algo = 1:length(app.meta.motion.algos)
                if strcmp(app.meta.motion.algos{algo},ma)
                    valid = 1;
                    ma_ind = algo;
                end
            end
            
            if valid
                clear app.UIAxes_2;
                %hold(app.UIAxes_2); % Plot is released
                for roi = 1:size(app.meta.motion.Results,1) % For every ROI
                    % From the entire motion vector of that ROI, cut out the current trial
                    mot_all = squeeze(app.meta.motion.Results(roi,ma_ind,:));
                    mot_all_norm = (mot_all - min(mot_all)) / (max(mot_all) - min(mot_all));  % normalize entire session motion to [0,1]
                    mot_trial = mot_all_norm(app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2));
                    
                    if roi == 1 
                        plot(1:length(mot_trial),mot_trial,'r', 'parent', app.UIAxes_2); hold(app.UIAxes_2); % Plot is hold
                    elseif roi == 2
                        plot(1:length(mot_trial),mot_trial+1.5,'g', 'parent', app.UIAxes_2); 
                    elseif roi == 3
                        plot(1:length(mot_trial),mot_trial+3,'b', 'parent', app.UIAxes_2); 
                    elseif roi ==4
                        plot(1:length(mot_trial),mot_trial+4.5,'m', 'parent', app.UIAxes_2);
                    end 
                end
                
                ax = app.UIAxes_2;
                % Define ylim 
                app.motion_plot.y_uplim = 1+(roi-1)*1.5;
                % Draw ylim and yticks
                eps = 0.1;
                ax.YLim = [0-eps app.motion_plot.y_uplim+eps];
                ax.YTick = 0:0.5:app.motion_plot.y_uplim; 
                
                % Define and draw yticklabels
                lab = 0:0.5:1;
                l = length(lab);
                for k = 1:roi-1
                    lab = [lab,lab(1:l)];
                end
                ax.YTickLabel = lab;  
                
                % Plot the black vertical bar that will be moving during playing the video
                app.motion_plot.timebar = line([app.vid.frame app.vid.frame], [0 app.motion_plot.y_uplim],'Color','black','parent', app.UIAxes_2);
            else
                fig = uifigure;
                uialert(fig,'Your specified motion method was not found. Please select another one','Retrieval error');
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
              
            global STOP;
            STOP = 0;

            % As long as video last frame has not been reached, show next frame
            while ~(app.vid.frame==app.vid.num_frames)
                
                app.vid.frame = app.vid.frame + 1;
                app.vid.img = read(app.vid.data,app.vid.frame);
                
                % Update the image plot
                if app.ShowROIsCheckBox.Value
                    app.showROI();
                else
                    imshow(app.vid.img,'parent',app.UIAxes);
                    drawnow;
                end
                
                % Update the motion vector plot
                delete(app.motion_plot.timebar)
                app.motion_plot.timebar = line([app.vid.frame app.vid.frame], [0 app.motion_plot.y_uplim],'Color','black','parent', app.UIAxes_2);
                
                % If the STOP button was pressed, interrupt
                if STOP 
                    break
                end
               
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        % Function executed when the stopp button is pressed
            global STOP
            STOP = 1;
            
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            % Restart button resets the video frame and shows the first frame in the video
            app.vid.frame = 1;
            app.vid.img = read(app.vid.data,app.vid.frame);
            if app.ShowROIsCheckBox.Value
                app.showROI();
            else
                imshow(app.vid.img,'parent',app.UIAxes);
                drawnow;
            end


            % Update the motion vector plot
            delete(app.motion_plot.timebar)
            app.motion_plot.timebar = line([app.vid.frame app.vid.frame], [0 app.motion_plot.y_uplim],'Color','black','parent', app.UIAxes_2);
                
            
        end

        % Value changed function: ShowROIsCheckBox
        function ShowROIsCheckBoxValueChanged(app, event)
            % Function is executed if show ROI checkbox is changed
            
           value = app.ShowROIsCheckBox.Value;
            
           % If ROIs should now be shown, open the showing function
           if value
               app.showROI();
           else
               % proceed one frame in video
                app.vid.frame = app.vid.frame + 1;
                app.vid.img = read(app.vid.data,app.vid.frame);
                imshow(app.vid.img,'parent',app.UIAxes);
                drawnow;
           end    
               
        end

        % Value changed function: ROIHEADEditField
        function ROIHEADEditFieldValueChanged(app, event)
            value = app.ROIHEADEditField.Value;
        end

        % Value changed function: ROILEFTPAWEditField
        function ROILEFTPAWEditFieldValueChanged(app, event)
            value = app.ROILEFTPAWEditField.Value;
        end

        % Value changed function: ROIRIGHTPAWEditField
        function ROIRIGHTPAWEditFieldValueChanged(app, event)
            value = app.ROIRIGHTPAWEditField.Value;
        end

        % Value changed function: ROIBACKEditField
        function ROIBACKEditFieldValueChanged(app, event)
            value = app.ROIBACKEditField.Value;
        end

        % Button pushed function: SaveallthresholdsButton
        function SaveallthresholdsButtonPushed(app, event)
            % This function is executed when all threshold are about to be saved
            
            trial = app.TrialnoEditField.Value;
            Colors = {'Red','Green','Blue','Magenta'};

            
            % Create struct and save other relevant variables
            results = struct;
            results.algo = app.MotionalgoDropDown.Value;
            results.ROIs = app.meta.motion.ROIs;
            results.thresholds = [app.ROIHEADEditField.Value app.ROILEFTPAWEditField.Value app.ROIRIGHTPAWEditField.Value app.ROIBACKEditField.Value];
          
            % Determin index of used motion algorithm
            for algo = 1:length(app.meta.motion.algos)
                if strcmp(app.meta.motion.algos{algo},app.MotionalgoDropDown.Value)
                    ma_ind = algo;
                end
            end
            
            results.raw_motion = squeeze(app.meta.motion.Results(:,ma_ind,:));
            results.binary_motion = zeros(size(results.raw_motion));  
            for roi = 1:size(results.binary_motion,1)
                mot_all = results.raw_motion(roi,:); % 
                mot_all_norm = (mot_all - min(mot_all)) / (max(mot_all) - min(mot_all));  % normalize to [0,1]
                results.binary_motion(roi,:) = mot_all_norm > results.thresholds(roi) ;
                
                % Show results to verify
                x = 1:length(app.meta.motion.Results(1,1,app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2)));
                length(x)
                y = results.binary_motion(roi,app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2));
                length(y)
                
                plot(1:length(app.meta.motion.Results(1,1,app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2))), ...
                      (roi-1)*1.5+results.binary_motion(roi,app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2)),'o',...
                      'Color',Colors{roi},'Linewidth', 2, 'parent', app.UIAxes_2);
                % If the motion detection method was Pearson R, we need to flip the binary vector (1 means no motion!)
                if strcmp(app.MotionalgoDropDown.Value, 'Pearson R')
                    results.binary_motion(roi,:) = ~results.binary_motion(roi,:);
                end     
            end
                %line([1 length(app.meta.motion.Results(1,1,app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2)))],...
                %        [threshold(roi)+(roi-1)*1.5 threshold(roi)+(roi-1)*1.5], 'Color',Colors{roi},'Linewidth', 2, 'parent', app.UIAxes_2);
            
            
            % Ask user whether the choise is final
            fig = uifigure;
            message = ['Doublecheck your thresholds (binarization predisplayed in the bottom figure). Confirming ', ...
                ' will binarize the vectors and save the results'];
            selection = uiconfirm(fig,message,'Confirm choice','Options',{'OK', 'Cancel'}, 'Defaultoption', 2, ...
            'Canceloption',2,'Icon','Warning');
            
            if strcmp(selection,'Cancel')
                close(fig)
            elseif strcmp(selection,'OK')
                
                results.thresholds = results.thresholds(results.thresholds~=0); % exclude unused ROIs
                slashes = strfind(app.vid.foldername,app.slash);
                save_path = strcat(app.vid.foldername(1:slashes(end)), app.slash, 'Motion_results_',results.algo);
                save(save_path, 'results')
                
                close(fig)
                
                fig = uifigure;
                uialert(fig,'The results have been saved successfully!','DONE!', ...
                    'Icon', 'success');
            end
        end

        % Value changed function: MotionalgoDropDown
        function MotionalgoDropDownValueChanged(app, event)
            value = app.MotionalgoDropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Video')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Motion vectors')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Norm. intensity')
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.TrialnoEditFieldLabel = uilabel(app.UIFigure);
            app.TrialnoEditFieldLabel.Text = 'Trial no.';
            app.TrialnoEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialnoEditField.ValueChangedFcn = createCallbackFcn(app, @TrialnoEditFieldValueChanged, true);
            app.TrialnoEditField.Value = 1;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.BinarizationthresholdsLabel = uilabel(app.UIFigure);
            app.BinarizationthresholdsLabel.Text = 'Binarization thresholds';
            app.ROIHEADEditFieldLabel = uilabel(app.UIFigure);
            app.ROIHEADEditFieldLabel.Text = 'ROI HEAD';
            app.ROIHEADEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROIHEADEditField.ValueChangedFcn = createCallbackFcn(app, @ROIHEADEditFieldValueChanged, true);
            app.ROILEFTPAWEditFieldLabel = uilabel(app.UIFigure);
            app.ROILEFTPAWEditFieldLabel.Text = 'ROI LEFT PAW';
            app.ROILEFTPAWEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROILEFTPAWEditField.ValueChangedFcn = createCallbackFcn(app, @ROILEFTPAWEditFieldValueChanged, true);
            app.ROIRIGHTPAWEditFieldLabel = uilabel(app.UIFigure);
            app.ROIRIGHTPAWEditFieldLabel.Text = 'ROI RIGHT PAW';
            app.ROIRIGHTPAWEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROIRIGHTPAWEditField.ValueChangedFcn = createCallbackFcn(app, @ROIRIGHTPAWEditFieldValueChanged, true);
            app.ROIBACKEditFieldLabel = uilabel(app.UIFigure);
            app.ROIBACKEditFieldLabel.Text = 'ROI BACK';
            app.ROIBACKEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROIBACKEditField.ValueChangedFcn = createCallbackFcn(app, @ROIBACKEditFieldValueChanged, true);
            app.SaveallthresholdsButton = uibutton(app.UIFigure, 'push');
            app.SaveallthresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallthresholdsButtonPushed, true);
            app.SaveallthresholdsButton.Text = 'Save all thresholds';
            app.ChoosefolderButton = uibutton(app.UIFigure, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Text = 'Choose folder';
            app.ShowROIsCheckBox = uicheckbox(app.UIFigure);
            app.ShowROIsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowROIsCheckBoxValueChanged, true);
            app.ShowROIsCheckBox.Text = 'Show ROIs';
            app.MotionalgoDropDownLabel = uilabel(app.UIFigure);
            app.MotionalgoDropDownLabel.Text = 'Motion algo';
            app.MotionalgoDropDown = uidropdown(app.UIFigure);
            app.MotionalgoDropDown.Items = {'Absolute difference', 'Pearson R', 'Lucas Kanade', 'Horn Schunck'};
            app.MotionalgoDropDown.ValueChangedFcn = createCallbackFcn(app, @MotionalgoDropDownValueChanged, true);
            app.MotionalgoDropDown.Value = 'Pearson R';
        end
    end

    methods (Access = public)

        % Construct app
        function app = motion_bin_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end