classdef video_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadvideobehavdataButton      matlab.ui.control.Button
        ChoosevideoButton             matlab.ui.control.Button
        StartvideoButton              matlab.ui.control.Button
        FramerelativetocurrentSliderLabel  matlab.ui.control.Label
        FramerelativetocurrentSlider  matlab.ui.control.Slider
        StopvideoButton               matlab.ui.control.Button
        ChopButton                    matlab.ui.control.Button
        TimeEditFieldLabel            matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        ReferenceDropDownLabel        matlab.ui.control.Label
        ReferenceDropDown             matlab.ui.control.DropDown
        AnnotateROIButton             matlab.ui.control.Button
        SelectmotionalgosButton       matlab.ui.control.Button
        ChopdetectmotionButton        matlab.ui.control.Button
    end

properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        % CLASS FOR VIDEO RETRIEVING, SYNCING AND CHOPPING GUI
        %
        % This class provides the code for a GUI that allows the user to 
        %   - load a mp4 video file of a mouse performing the multimodal behavioral task in (1)
        %       - loading a video additionally loads the behavioral metafile of the same day/session/animal
        %   - synchronize the video data frames with the behavioral time stamps
        %       - Problem: The onset of the video file is not aligned with time 0 in the behavioral time stamps
        %       - Solution: The user watches the video until he/she sees the first visual reflection / flickering
        %           below the mouse (use start and stop button. The exact frame of the first flickering can be 
        %           choosen via the slider.
        %       - By clicking "SAVE" the frame currently displayed in the preview is set to the behavioral 
        %           timestamp of the first visual stimulus. The video is then chopped into one bit per trial.
        %
        % (1) "Context-dependent cortical integration of visual and somatosensory stimuli in behaving mice"
        %           Moritz Buchholz, Yaroslav Sych, Fritjof Helmchen, Asli Ayaz
        %


        % Defining class variables
        
        vid;    % struct for video metadata
        behav;  % struct for behavioral metadata
        
        % ugly code
        ma_fig; % alternative figure
        ConfirmButton;
        selection;


    end

    methods (Access = private)
    
        function results = LoadMetadata(app)
            
            slash = '/'; % CHANGE THIS TO / if you work on a MAC
            %root = 'H:\data\behavior\';
            root = '/Users/jannisborn/Desktop/HIFO/motion_analysis/behavior/';
            
            % Loads behavioral metad
            path = app.vid.filepath
            app.behav = struct();
            app.behav.perf_smooth_window = 40; % size of smoothing window to measure performance
            
            % Detection requests that 
            %   1) the date follows the '/' after the only '/body' string in all but the
            %           last 10 chars of the string
            %   2) the date has 7! chars
            %   3) the file name starts with the animal name followed by '-'
            %   4) the session name follows the animal name and is prefixed and
            %       suffixed by '.' 
            path(1:length(path)-10)
            date_onset = strfind(path(1:length(path)-10),strcat(slash,'body')) + 6;
            app.behav.date = path(date_onset:date_onset+7); % date has fixed length
            app.behav.date
            
            
            animal_on = strfind(path,slash); animal_on=animal_on(end)+1;
            % animal has not fixed length, animal_off is relative to animal_on
            animal_off = strfind(path(animal_on:end),'-'); 
            animal_off = animal_off(1) + animal_on - 2;
            app.behav.animal = path(animal_on:animal_off);
    
            
            % session follows straight after the animal, separated by -
            session_on = animal_off + 2;
            session_off = strfind(path(session_on:end),'.'); 
            session_off = session_on + session_off(1) - 2; % in case > 1 '-' in filename
            app.behav.session = path(session_on:session_off);
            
            try
                app.behav.folder_path = strcat(root,app.behav.date,slash,app.behav.animal,slash,...
                    app.behav.session(2:end),slash);
            catch 
                fig = uifigure;
                uialert(fig,'Behavioral file could not found. Please specify by hand.','Invalid file path');
                app.behav.folder_path = uigetdir();
            end
              
            %% The file names are inferred so the meta data can be load with existing functions
            filename_meta = strcat(app.behav.animal,'-',app.behav.session,'-exp.txt');
            app.behav.metastats = readExperimentData(app.behav.folder_path,filename_meta);
            app.behav.stats = getBehavior(app.behav.metastats,app.behav.perf_smooth_window);
            
            % First, we need the absolute time of the experiment begin (start of first trial)
            filename = strcat(app.behav.animal,'-',app.behav.session,'-t1.txt');
            fid = fopen(strcat(app.behav.folder_path,filename)); % e.g. '5627rr-s3-t1.txt'
            C = textscan(fid, '%s','delimiter', '\t');
            fclose(fid);
            app.behav.exp_start = datetime([C{1}{1},C{1}{2}],'Format','dd.MM.yyyy HH:mm:ss.SSSS');
            
            
            % Next, we need the first trial in which a visual stimulus occurred. This would be the trials with 
            % nature "V" or "V+S", but if response was early lick (EL), the visual stimulus had no chance to appear.
            % In case of V or V+S + EL, no field in textfile has value 'stim'.
            app.vid.first_v = 1;
            idx = 0;
            while idx == 0
                while app.vid.first_v <=  length(app.behav.metastats.stim) && ...
                        (isempty(strfind(app.behav.metastats.stim{app.vid.first_v},'V')) || ...
                        strcmp(app.behav.metastats.beh{app.vid.first_v},'EL'))
                    app.vid.first_v = app.vid.first_v+1;
                end
                
                % Error handling
                if app.vid.first_v > length(app.behav.metastats.stim)
                    error("This session does not contain any trial with visual stimulation.")
                end
                
                % Next, we need the time of the visual stimulus onset in that trial
                filename_first_v = strcat(app.behav.animal,'-',app.behav.session,'-t',num2str(app.vid.first_v),'.txt')
                fid = fopen(strcat(app.behav.folder_path,filename_first_v)); % e.g. '5627rr-s3-t2.txt'
                C = textscan(fid, '%s','delimiter', '\t');
                fclose(fid);
                
                idx = min(find(contains(C{1},'stim'))) - 4;
            end
           
            day_time = [C{1}{idx-1},C{1}{idx}]; % day_time as a string
            % Precise absolute time of first visual stimulus
            app.behav.first_vis_stim = datetime(day_time,'Format','dd.MM.yyyy HH:mm:ss.SSSS');
            app.behav.first_vis_stim
            
            % Alternatively, we may need the time stamp of the first offset of full(!) stage drive-in
            % Even in 'N' trials, the stage partially moves in. However, here we refer to trials that have a
            % somatosensory stimulation (i.e. full stage drive in)
            % Conceptually, similar to above
            app.vid.first_stage = 1;
            idx = 0;
            
            while idx == 0
                while app.vid.first_stage <=  length(app.behav.metastats.stim) && ...
                        isempty(strfind(app.behav.metastats.stim{app.vid.first_stage},'S')) || ...
                        strcmp(app.behav.metastats.beh{app.vid.first_stage},'EL')
                    app.vid.first_stage = app.vid.first_stage+1;
                end
                
                % Error handling
                if app.vid.first_stage > length(app.behav.metastats.stim)
                    error("This session did not contain any trial with sensory stimulation.")
                    idx = -1;
                
                else 
                    % Next, we need the time when the stage stopped its drive-in
                    filename_first_v = strcat(app.behav.animal,'-',app.behav.session,'-t',num2str(app.vid.first_stage),'.txt');
                    fid = fopen(strcat(app.behav.folder_path,filename_first_v)); % e.g. '5627rr-s3-t2.txt'
                    C = textscan(fid, '%s','delimiter', '\t');
                    fclose(fid);
                    
                    % Looking exactly for 'in' and taking max since the last time stamp is when stage stops
                    idx = max(find(strcmp(C{1},'in'))) - 4;
                end 
             end           
            
            if idx ~= -1
                day_time = [C{1}{idx-1},C{1}{idx}]; % day_time as a string
                app.behav.first_stage = datetime(day_time,'Format','dd.MM.yyyy HH:mm:ss.SSSS');
            
           
                % Next, we need the durations of every trial (for chopping)
                app.vid.trial_onsets = cell(app.behav.metastats.n_trials,1);
                app.vid.trial_offsets = cell(app.behav.metastats.n_trials,1);
            end
            
            for trial = 1:app.behav.metastats.n_trials
        
                fid = fopen(strcat(app.behav.folder_path, ...
                    app.behav.animal,'-',app.behav.session,'-t',num2str(trial),'.txt'));
                C = textscan(fid, '%s','delimiter', '\t');
                fclose(fid);
                
                % Detect start and end of trial, if multiple occurrences (some files are defective) -> take first 
                start_ind = find(contains(C{1},'Trial Start')); start_ind = start_ind(1);
                end_ind = find(contains(C{1},'Trial End')); end_ind = end_ind(1);
                
                app.vid.trial_onsets{trial} = datetime([C{1}{start_ind-4},C{1}{start_ind-3}],'Format','dd.MM.yyyy HH:mm:ss.SSSS');
                app.vid.trial_offsets{trial} = datetime([C{1}{end_ind-4},C{1}{end_ind-3}],'Format','dd.MM.yyyy HH:mm:ss.SSSS');


            end
            
            % Option to detect motion defaults to False. User can turn on by pushing "Chop + detect motion" button.
            app.vid.motion_detect = 0;
            
            
        end


        function t = chunk_vid(app)
            
            % This method chops the video of the entire session into chunks (one per trial) 
            % and saves them individually.
                        
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait','Message','Prepare chunking');
            
            % index to reference frame - either first occurrence of visual stimulus or stage drive-in
            app.vid.ref = app.vid.frame; 
            app.vid.ref_rel_time = app.vid.ref/app.vid.data.FrameRate; % relative time in seconds
            
            if strcmp(app.ReferenceDropDown.Value,'Onset of visual reflection ')  
                app.behav.ref = app.behav.first_vis_stim; 
            elseif strcmp(app.ReferenceDropDown.Value,'Offset of stage drive-in')
                app.behav.ref = app.behav.first_stage;
            end
            % Thus app.behav.ref is also an absolute time!
            
            if app.vid.motion_detect
                % The results of the motion analysis are saved in the motion struct.
                % It contains a list of applied motion algorithms, a list of preselected ROIs and a cell of Results
                % of the motion analysis. There is one result vector of length #exp_frames per ROI and per motion algo.
                % Then, it contains how many trials the session had and a matrix called bounds, that indicates which
                % exp_frames contain to start and end of a trial.
                motion = struct();
                motion.algos = app.vid.ROI.motion_algorithms;
                motion.ROIs = app.vid.ROI.Names;
                length(motion.ROIs)
                length(motion.algos)
                app.vid.data.FrameRate
                motion.Results = zeros(length(motion.ROIs),length(motion.algos),app.vid.data.FrameRate);
                motion.num_trials = app.behav.metastats.n_trials;
                motion.trial_nature = app.behav.metastats.stim;
                motion.responses = app.behav.metastats.beh;
                motion.bounds = zeros(motion.num_trials, 2);
                motion.last_valid_trial = app.vid.last_valid_trial;
            end
       

            % Now we need index to frame that corresponds to experiment onset
            % How many frames were in between reference and experiment start
            frames_back = floor(seconds(app.behav.ref - app.behav.exp_start)*app.vid.data.FrameRate);
            app.vid.first_exp_frame = app.vid.ref - frames_back; % Go back to first relevant frame
            
            % Debugging: Compute index of last frame and verify that is still in the video:
            last_frame = app.vid.first_exp_frame + floor(app.vid.data.FrameRate*seconds(...
                app.vid.trial_offsets{app.behav.metastats.n_trials} - app.behav.exp_start));
            strcat('Last trial offset at ')
            app.vid.trial_offsets{app.behav.metastats.n_trials}
            try
                read(app.vid.data,last_frame)
                app.vid.last_valid_trial = app.behav.metastats.n_trials;
            catch
    
                %%%%%%%%%% DEBUGGING CODE %%%%%%%%%%%%%%%
%                 bounds = zeros(2,app.behav.metastats.n_trials);
%                 for trial = 1:app.behav.metastats.n_trials
%                      % Find start and end frame of that trial
%                     timedelta = seconds(app.vid.trial_onsets{trial} - app.behav.exp_start); % convert duration to sec
%                     bounds(1,trial) = app.vid.first_exp_frame + floor(timedelta*app.vid.data.FrameRate);
%                     timedelta = seconds(app.vid.trial_offsets{trial} - app.behav.exp_start);
%                     bounds(2,trial) = app.vid.first_exp_frame + floor(timedelta*app.vid.data.FrameRate);
%                     %read(app.vid.data, bounds(1,trial));
%                     %read(app.vid.data, bounds(2,trial));
%                 end
%                 
%                 figure
%                 plot(bounds(1,:), ones(1,app.behav.metastats.n_trials),'bo'); hold on;
%                 plot(bounds(2,:),2*ones(1,app.behav.metastats.n_trials),'ro'); 
%                 x = floor(app.vid.data.Duration)*app.vid.data.FrameRate *ones(34,1);
%                 y = 0:3:100;
%                 ylim([0,3]);
%                 line(x,y)
%                 
%                 % Find out what frame the onset of the first trial has:
%                 app.behav.exp_start % = app.vid.trial_onsets{1}
%                 strcat('Ref frame ', num2str(app.vid.ref), ' frames back ', num2str(frames_back), ' ref ')
%                 app.behav.ref
%                 timedelta = seconds(app.vid.trial_onsets{1} - app.behav.exp_start); % convert duration to sec
%                 start_frame  = app.vid.first_exp_frame + floor(timedelta*app.vid.data.FrameRate);
%                 strcat('The first trial starts at frame ', num2str(start_frame), ', i.e. second ', num2str(start_frame/app.vid.data.FrameRate))
                %%%%%%%%%% END DEBUGGING CODE %%%%%%%%%%%%%%%
                
                
                choice = app.find_last_valid_trial();
                
                if strcmp(choice, 'Cancel')
                    error("You decided not to perform the analysis on the subset of trials. Re-select the reference frame or discard the video.")
                end
                % If this point is reached, the analysis is performed

            end
            
            
            % Debugging variable
            end_reached = 0;
            
            % Create folder for chunks
            mkdir(strcat(app.vid.foldername,'/chunked'));
                    
            counter = 1;
            a=cell(2,3);
            % Now iterate over all trials and cut the data
            for trial = 1:app.vid.last_valid_trial
                
                % Find start and end frame of that trial
                timedelta = seconds(app.vid.trial_onsets{trial} - app.behav.exp_start); % convert duration to sec
                start_frame = app.vid.first_exp_frame + floor(timedelta*app.vid.data.FrameRate);
                timedelta = seconds(app.vid.trial_offsets{trial} - app.behav.exp_start);
                end_frame = app.vid.first_exp_frame + floor(timedelta*app.vid.data.FrameRate);
                
                % Error handling
                if start_frame < 0
                    fig = uifigure;
                    uialert(fig,'Attempted to access negative video index. Your specified reference frame in the video was likely wrong. Consider consulting the behavioral file itself.','Index error');
                    error('Attempted to access negative video index. ')
                end


                % Video preparation
                filename = strcat(strcat(app.vid.foldername,'chunked/Trial_'),num2str(trial));
                chunked = VideoWriter(filename,'mpeg-4');
                open(chunked);
                
                if app.vid.motion_detect
                    % motion detection preparation
                    motion.bounds(trial,1) = counter;
                    old = rgb2gray(read(app.vid.data,start_frame)); % first frame
                end
                    
                % CREATE VIDEO & PERFORM MOTION DETECTION ON THE FLY
                for ind = start_frame+1:end_frame
                    
                    % write to regular, chunked video (without motion detection)
                    writeVideo(chunked,old);
                    % Motion detection
                    try 
                        new = rgb2gray(read(app.vid.data,ind));
                    catch
                        warning(strcat('Trial no. ', num2str(trial), ' was not found anymore.'))
                        end_reached = 1;
                    end
                    
                    if app.vid.motion_detect && ~end_reached
                        % Write motion results
                        for ma = 1:length(motion.algos)
                            motion.Results(:,ma,counter) = find_motion(app,old,new,ma);
                        end
                        % Preparation for next iteration
                        counter = counter+1;
                    end
                    old = new;
                end
                
                % Leave loop if end of video was reached before 
                if end_reached
                    break
                end
                
                if app.vid.motion_detect
                    motion.bounds(trial,2) = counter;
                end

                % Display progress
                d.Value = trial/app.behav.metastats.n_trials;
                d.Message = strcat("Having completed ",num2str(trial)," out of  ",...
                    num2str(app.behav.metastats.n_trials)," trials.");
            end
            
            if app.vid.motion_detect
                % Save the motion struct
                save(strcat(app.vid.foldername,'motion_analysis'),'motion');
            end
                   
            % When all videos have been chunked successfully, we save a metafile with the class variables 
            % to allow restoring the ROI and other parameters of interest.
            behav = app.behav; vid = app.vid;
            save(strcat(app.vid.foldername,'UI_metadata'), 'behav', 'vid')
            
            close(d)

        end
    
    
        function result = find_motion(app,old,new,ma)
            % Function that performs the motion detection.
            % 
            % OLD/NEW       [uint8] - Previous/Current frame
            % MA            [int] - index of motion detection algorithm (
            % ROI           [int] - index region of interest (e.g. 'Head')
            
            % Infer motion algorithm and convert to linear indices
            motion_algorithm = app.vid.ROI.motion_algorithms{ma};
            %inds = sub2ind(size(old),app.vid.ROI.inside{roi}(:,1),app.vid.ROI.inside{roi}(:,2));
            result = zeros(length(app.vid.ROI.Names),1);

            % First list methods that compute a single value for the entire ROI - these methods do not require averaging
            if strcmp(motion_algorithm, 'Pearson R')
                for roi = 1:length(app.vid.ROI.Names)
                    inds = sub2ind(size(old),app.vid.ROI.inside{roi}(:,1),app.vid.ROI.inside{roi}(:,2));
                    tmp = corrcoef(double(old(inds)),double(new(inds)));
                    result(roi) = tmp(1,2);
                end            
            else 
            % Then list methods that have pixel-wise results - these methods requre averaging across these pixels
                if strcmp(motion_algorithm,'Absolute difference')
                    motion = imabsdiff(old,new);
                elseif strcmp(motion_algorithm,'Lucas Kanade')
                    opticFlow = opticalFlowLK('NoiseThreshold',0.009); % optical flow estimator specifications 
                    flow = estimateFlow(opticFlow,new);
                    motion = flow.Magnitude;
                elseif strcmp(motion_algorithm,'Horn Schunck')
                    opticFlow = opticalFlowHS;
                    flow = estimateFlow(opticFlow,new);
                    motion = flow.Magnitude;   
                end
                
                % Convert ROI indices to linear indices and take the mean of all values in the ROI
                for roi = 1:length(app.vid.ROI.Names)
                    inds = sub2ind(size(old),app.vid.ROI.inside{roi}(:,1),app.vid.ROI.inside{roi}(:,2));
                    result(roi) = mean(motion(inds));
                end
            end 
        end   
                
            
        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % This function is executed if the confirmation button in the motion algorithm selection window is pushed
           
            % Iterate over potential methods and delete the unselected ones 
            for k = length(app.vid.ROI.motion_algorithms):-1:1
                if ~app.vid.ROI.checkbox{k}.Value
                    app.vid.ROI.motion_algorithms(k) = [];
                end
            end
            close(app.ma_fig);
        end 


        function GuideSynchronization(app)
            % This function guides the user through the synchronization of videos and behavioral data by retrieving the first 10 
            % stimuli/response pairs and suggesting the correct trial to perform the chopping
            
            %fig = uifigure('Name','Synchronization guidance');
            stim =  app.behav.metastats.stim;
            beh = app.behav.metastats.beh;
            
            % Display responses and stimuli of the first trials
            msg = sprintf(strcat("The first 20 stimuli are:\n ", string(strjoin(stim(1:20))), "\n", ...
                 "The first 20 responses are:\n ", string(strjoin(beh(1:20))), "\n\n"));

             % Find the trials that are suitable for synchronization: 
                % If reflection: (V or V+S) and not EL
                % If stage     : (S or V+S) and not EL
            reflection_t = 1;
            while reflection_t < 20 && ~ ((strcmp(stim(reflection_t),'V') || strcmp(stim(reflection_t),'V+S')) && ...
                    ~strcmp(beh(reflection_t),'EL'))
                reflection_t = reflection_t + 1;
            end
            
            stage_t = 1;
            while stage_t < 20 && ~((strcmp(stim(stage_t),'S') || strcmp(stim(stage_t),'V+S')) && ...
                    ~strcmp(beh(stage_t),'EL'))
                stage_t = stage_t + 1;
            end
            
            % Now retrieve the absolute time of the start trials (to give the user a hint on how long he needs to wait, roughly)
            filenames = [strcat(app.behav.animal,"-",app.behav.session,'-t',num2str(1),'.txt'),...
                strcat(app.behav.animal,"-",app.behav.session,'-t',num2str(reflection_t),'.txt'), ...
                strcat(app.behav.animal,"-",app.behav.session,'-t',num2str(stage_t),'.txt')];
            % First trial, reflection trial, stage_trial
            times =  cell(3,1);    
            for ind = 1:length(filenames)
                strcat(app.behav.folder_path,filenames(ind))
                fid = fopen(strcat(app.behav.folder_path,filenames(ind))); % e.g. '5627rr-s3-t2.txt'
                C = textscan(fid, '%s','delimiter', '\t');
                fclose(fid);
                if ind == 1
                    idx = min(find(contains(C{1},'Trial Start'))) - 3;
                elseif ind == 2
                    idx = min(find(contains(C{1},'stim'))) - 4;
                elseif ind == 3
                    idx = min(find(contains(C{1},'in'))) - 4;
                end 
                day_time = [C{1}{idx-1},C{1}{idx}]; % day_time as a string
                times{ind} = datetime(day_time,'Format','dd.MM.yyyy HH:mm:ss.SSSS');
            end


            msg = sprintf(strcat(msg, "If you can see the visual reflection in the video, use trial ", num2str(reflection_t), " for synching, i.e. the ", ...
            "reflection is ", char(times{2}-times{1}) , " after experiment start.\n ", ...
            "If you can only see the stage drive-in, use trial ", num2str(stage_t), " for synching, i.e. the drive-in ", ...
            "is ", char(times{3}-times{1}),  " after experiment start.\n \n", ...
            "Note that these times differ from the duration you have to watch the video (it's your task to detect how much)"));
      
                
            uialert(app.UIFigure,msg,'Suggestions for synchronization trial', 'Icon', 'info')
        end
        
        function selection = find_last_valid_trial(app)
            % This function is called if the frame corresponding to the end of the last trial 
            % (according to behavioral files) is not in the video anymore.
            % It finds the last valid trial and leaves the choice to the user whether the analysis
            % should be performed on the fraction of trials.
            
               
            % If index of last frame is not in video anymore, find the last trial that is fully captured
            trying = true;
            try_trial = app.behav.metastats.n_trials - 1;
            
            while trying
                
                % Last frame of the trial we currently look at (order: n_trials-1, ..., 1)
                frame = app.vid.first_exp_frame + floor(app.vid.data.FrameRate*seconds(...
                            app.vid.trial_offsets{try_trial} - app.behav.exp_start));
                try
                    read(app.vid.data, frame);
                    trying = false;
                catch
                    try_trial = try_trial - 1;
                end
            end
            
            app.vid.last_valid_trial = try_trial;
             
            % Now try_trial contains the last trial that is still accessible in the video. 
            fig = uifigure;
            message = ['This session contains ', num2str(app.behav.metastats.n_trials), ' trials, however only ', num2str(try_trial), ...
                ' are fully video-mointored (according to your current reference frame). Select CANCEL to quit the analysis ', ...
                ' or Analyse anyways instead. Be extremly careful with the latter option. Make sure, that the missing trials ', ...
                ' are at the end and NOT at the beginning of the session, otherwise you just get noise.'];
            selection = uiconfirm(fig,message,'Confirm choice','Options',{'Cancel', 'Analyse anyways'}, 'Defaultoption', 1, ...
            'Canceloption',1,'Icon','Warning');
            close(fig)            

            
        end

        
    end


    methods (Access = private)

        % Button pushed function: ChoosevideoButton
        function ChoosevideoButtonPushed(app, event)
            
            % Allocation
            app.vid = struct();
            
            % Open a mp4 file
            %cd 'J:\data\body\';
            cd '/Users/jannisborn/Desktop/HIFO/motion_analysis/behavior/';
            [app.vid.filename, app.vid.foldername] = uigetfile('.mp4');
            app.vid.filepath = fullfile(app.vid.foldername, app.vid.filename);
                      
        end

        % Button pushed function: LoadvideobehavdataButton
        function LoadvideobehavdataButtonPushed(app, event)
            % Function is executed if Load video button is pushed.
            
            % Create video object
            app.vid.data = VideoReader(app.vid.filepath);
            
            if app.vid.data.FrameRate ~= 20
                error('The frame rate is other than 20, the code will not generate reliable results.');
            end
            
            app.vid.num_frames = app.vid.data.Duration * app.vid.data.FrameRate;
            app.vid.frame = 1; % points to the current index of the video
            imshow(read(app.vid.data,1),'parent',app.UIAxes);
            
            txt = 'Image has been loaded succesfully. Behavioral files are now searched and retrieved.';
            disp(txt);
            app.LoadMetadata();
            disp('Metadata succesfully retrieved'); 
            app.GuideSynchronization();

        end

        % Callback function
        function SecondstodisplayEditFieldValueChanged(app, event)
        % Function deprecated
        end

        % Callback function
        function DISPLAYButtonPushed(app, event)
        % Function deprecated
        end

        % Button pushed function: StartvideoButton
        function StartvideoButtonPushed(app, event)
            % Function executed when the Start-Video button is pressed
            
            global STOP;
            STOP = 0;

            % As long as video last frame has not been reached, show next frame
            while ~(app.vid.frame==app.vid.num_frames)
                
                app.vid.frame = app.vid.frame + 1;
                app.TimeEditField.Value = round(app.vid.frame / app.vid.data.FrameRate,1); % Update text box displaying position of video
                imshow(read(app.vid.data,app.vid.frame),'parent',app.UIAxes);
                drawnow;
                
                % If the STOP button was pressed, interrupt
                if STOP 
                    break
                end
               
            end
        end

        % Value changed function: FramerelativetocurrentSlider
        function FramerelativetocurrentSliderValueChanged(app, event)
            % Function is executed if the slider is moved
            
            % Set slider value to the current frame           
            app.vid.frame = app.vid.frame + round(app.FramerelativetocurrentSlider.Value); 
            app.TimeEditField.Value = round(app.vid.frame / app.vid.data.FrameRate,1); % Adjust text box
            imshow(read(app.vid.data,app.vid.frame),'parent',app.UIAxes); % show new frame
            drawnow
            
            app.FramerelativetocurrentSlider.Value = 0; % Reset Slider value

            
        end

        % Button pushed function: ChopButton
        function ChopButtonPushed(app, event)
            % Executed when the Save button is pushed
            
            fig = uifigure;
            message = ['Your reference choice is: ', upper(app.ReferenceDropDown.Value), ' Preferred option', ...
                ' is onset of visual reflection. If you choose the stage, make sure the stage has FULLY moved in, i.e. you',...
                ' saw either a S or a V+S trial but not a N or a V trial - where the stage also partially moves in.',...
                ' This method FAILS otherwise. Confirming will chop the entire video into chunks for every trial.'];
            selection = uiconfirm(fig,message,'Confirm choice','Options',{'OK', 'Cancel'}, 'Defaultoption', 2, ...
            'Canceloption',2,'Icon','Warning');
            close(fig)
            
            if strcmp(selection,'Cancel')
                42;
            elseif strcmp(selection,'OK')
                app.chunk_vid();
            end
                
        end

        % Button pushed function: StopvideoButton
        function StopvideoButtonPushed(app, event)
            % Function executed when the stopp button is pressed
            global STOP
            STOP = 1;
        end

        % Value changed function: ReferenceDropDown
        function ReferenceDropDownValueChanged(app, event)
            app.vid.ref = app.ReferenceDropDown.Value;
        end

        % Button pushed function: AnnotateROIButton
        function AnnotateROIButtonPushed(app, event)
            % This function is executed when the "Annotate ROI" button is hit.
            
            % Create the class variables
            app.vid.ROI = struct();
            app.vid.ROI.Names = {'Head', 'Left paw', 'Right paw', 'Back'};
            app.vid.ROI.motion_algorithms = {'Absolute difference','Pearson R','Lucas Kanade','Horn Schunck'};
            
            % Just allocation, will be replaced
            for k = 1:length(app.vid.ROI.Names)
                app.vid.ROI.raw{k} = 0;        % Raw data returning from ImROI
                app.vid.ROI.inside{k} = 0;     % All points lying inside ROI 
                app.vid.ROI.polygon{k} = 0;    % Points specifying the polygon
                
            end
            img = read(app.vid.data,app.vid.frame);

            % First display an information window
            message = sprintf(['You will now draw lines around the regions of interest (ROI) for motion analysis. There ', ...
                'will be one ROI for 1) the ' , app.vid.ROI.Names{1}, ' 2) the ', app.vid.ROI.Names{2} ,' 3) the ', ...
                app.vid.ROI.Names{3},' and 4) ', app.vid.ROI.Names{4}], '.');
            uiwait(msgbox(message));
            
            % Speciifes wether image for ROI annotation is shown fullscreen
            fsc = 1;
            
            % For every ROI, call the drawing function
            for k = 1:length(app.vid.ROI.Names)
                 app.vid.ROI.raw{k} = ImROI(img, app.vid.ROI.Names{k}, fsc );
                 
                 % If user found the ROI and the above method was successful, we explicitly save 
                 % all points lying in the ROI.
                 if app.vid.ROI.raw{k}.success
                    app.vid.ROI.inside{k} = [app.vid.ROI.raw{k}.row, app.vid.ROI.raw{k}.col];
                    app.vid.ROI.polygon{k} = [app.vid.ROI.raw{k}.x_poly, app.vid.ROI.raw{k}.y_poly];
                 end
            end
            
            % Delete ROIs that were not selected
            for k = length(app.vid.ROI.Names):-1:1
                if ~app.vid.ROI.raw{k}.success
                    app.vid.ROI.Names(k) = [];
                    app.vid.ROI.inside(k) = [];
                    app.vid.ROI.polygon(k) = [];
                    app.vid.ROI.raw(k) = [];
                end
            end
            
            figure(app.UIFigure);

        end

        % Button pushed function: SelectmotionalgosButton
        function SelectmotionalgosButtonPushed(app, event)
            % Function is executed when the Motion algorithm button is pushed
            
            app.ma_fig = uifigure;
            txt = sprintf('%s\n%s','Here you can select which motion detection algorithms should be applied. Per default, ',...
                'all will be applied. Untick to turn them off and speed up processing. Confirm with okay.');     
            info = uilabel(app.ma_fig,'Text',txt,'Position',[50 300 500 32]);
           
            
            for k = 1:length(app.vid.ROI.motion_algorithms)
                app.vid.ROI.checkbox{k} = uicheckbox(app.ma_fig,'Text',app.vid.ROI.motion_algorithms{k},'Value',1,'Position',[150 200-k*25 150 15]);
            end 
              
            % Create OkayButton
            app.ConfirmButton = uibutton(app.ma_fig, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Position = [200 50 100 22];
            app.ConfirmButton.Text = 'Confirm!';
        end

        % Button pushed function: ChopdetectmotionButton
        function ChopdetectmotionButtonPushed(app, event)
            % This function is executed when the button "Chop + detect motion" is pushed.
            % It sets the motion detection option to true and then calls the chopping function.
            
            app.vid.motion_detect = 1;
            app.ChopButtonPushed(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadvideobehavdataButton = uibutton(app.UIFigure, 'push');
            app.LoadvideobehavdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideobehavdataButtonPushed, true);
            app.LoadvideobehavdataButton.Text = 'Load video + behav data';
            app.ChoosevideoButton = uibutton(app.UIFigure, 'push');
            app.ChoosevideoButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosevideoButtonPushed, true);
            app.ChoosevideoButton.Text = 'Choose video';
            app.StartvideoButton = uibutton(app.UIFigure, 'push');
            app.StartvideoButton.ButtonPushedFcn = createCallbackFcn(app, @StartvideoButtonPushed, true);
            app.StartvideoButton.Text = 'Start video';
            app.FramerelativetocurrentSliderLabel = uilabel(app.UIFigure);
            app.FramerelativetocurrentSliderLabel.Text = 'Frame (relative to current)';
            app.FramerelativetocurrentSlider = uislider(app.UIFigure);
            app.FramerelativetocurrentSlider.Limits = [-20 20];
            app.FramerelativetocurrentSlider.ValueChangedFcn = createCallbackFcn(app, @FramerelativetocurrentSliderValueChanged, true);
            app.StopvideoButton = uibutton(app.UIFigure, 'push');
            app.StopvideoButton.ButtonPushedFcn = createCallbackFcn(app, @StopvideoButtonPushed, true);
            app.StopvideoButton.Text = 'Stop video';
            app.ChopButton = uibutton(app.UIFigure, 'push');
            app.ChopButton.ButtonPushedFcn = createCallbackFcn(app, @ChopButtonPushed, true);
            app.ChopButton.Text = 'Chop!';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Video')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ReferenceDropDownLabel = uilabel(app.UIFigure);
            app.ReferenceDropDownLabel.Text = 'Reference:';
            app.ReferenceDropDown = uidropdown(app.UIFigure);
            app.ReferenceDropDown.Items = {'Onset of visual reflection ', 'Offset of stage drive-in'};
            app.ReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceDropDownValueChanged, true);
            app.ReferenceDropDown.Value = 'Onset of visual reflection ';
            app.AnnotateROIButton = uibutton(app.UIFigure, 'push');
            app.AnnotateROIButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotateROIButtonPushed, true);
            app.AnnotateROIButton.Text = 'Annotate ROI';
            app.SelectmotionalgosButton = uibutton(app.UIFigure, 'push');
            app.SelectmotionalgosButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmotionalgosButtonPushed, true);
            app.SelectmotionalgosButton.Text = 'Select motion algos';
            app.ChopdetectmotionButton = uibutton(app.UIFigure, 'push');
            app.ChopdetectmotionButton.ButtonPushedFcn = createCallbackFcn(app, @ChopdetectmotionButtonPushed, true);
            app.ChopdetectmotionButton.Text = 'Chop + detect motion!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = video_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end