classdef motion_bin_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        PlayButton                   matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        RestartButton                matlab.ui.control.Button
        TrialnoEditFieldLabel        matlab.ui.control.Label
        TrialnoEditField             matlab.ui.control.NumericEditField
        LoadButton                   matlab.ui.control.Button
        BinarizationthresholdsLabel  matlab.ui.control.Label
        ROI1EditFieldLabel           matlab.ui.control.Label
        ROI1EditField                matlab.ui.control.NumericEditField
        ROI2EditFieldLabel           matlab.ui.control.Label
        ROI2EditField                matlab.ui.control.NumericEditField
        ROI3EditFieldLabel           matlab.ui.control.Label
        ROI3EditField                matlab.ui.control.NumericEditField
        ROI4EditFieldLabel           matlab.ui.control.Label
        ROI4EditField                matlab.ui.control.NumericEditField
        SaveallthresholdsButton      matlab.ui.control.Button
        ChoosefolderButton           matlab.ui.control.Button
        ShowROIsCheckBox             matlab.ui.control.CheckBox
        MotionalgoDropDownLabel      matlab.ui.control.Label
        MotionalgoDropDown           matlab.ui.control.DropDown
    end


    properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %
        % CLASS FOR BINARIZING MOTION VALUES
        %
        % ADD README
        %
        % (1) "Context-dependent cortical integration of visual and somatosensory stimuli in behaving mice"
        %           Moritz Buchholz, Yaroslav Sych, Fritjof Helmchen, Asli Ayaz
        %


        % Defining class variables
        
        vid;    % struct for video metadata
        meta;  % struct for behavioral metadata
        slash;
        motion_plot; % For the motion plot, updated every frame


    end

    methods (Access = private)
    
        function results = showROI(app)
            % This function shows the ROI on the current image
            img = app.vid.img;
            for roi = 1:length(app.meta.UI.vid.ROI.inside) % For every ROI
                %roi_points = app.meta.UI.vid.ROI.polygon{roi}; % Retrieve points drawing polygon
                % Reshape and round, axis are still inverted.
                %roi_points = round(reshape(roi_points, [length(roi_points)/2,2]));
                %tmp = roi_points;
                %roi_points = [tmp(:,2) tmp(:,1)]; % switch columns
                
                roi_points = app.meta.UI.vid.ROI.inside{roi};
                for l = 1:length(roi_points) % For every point in ROI
                    if roi == 1
                        img(roi_points(l,1),roi_points(l,2),:) = [255 0 0]; % Mark it red
                    elseif roi == 2
                        img(roi_points(l,1),roi_points(l,2),:) = [0 255 0]; % Mark it green
                    elseif roi == 3
                        img(roi_points(l,1),roi_points(l,2),:) = [0 0 255]; % Mark it blue
                    elseif roi ==4
                        img(roi_points(l,1),roi_points(l,2),:) = [255 0 255]; % Mark it magenta
                    end 
                end
            end
            imshow(img,'parent',app.UIAxes)
            drawnow;
   
        end
        
    end


    methods (Access = private)

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
             % Allocation
            app.vid = struct();
            app.slash = '/' ; % CHANGE THIS TO '/' ON MAC AND '\' ON WINDOWS
    
            % Open a folder
            app.vid.foldername = uigetdir();
            
            hold(app.UIAxes_2); % Plot is hold

        end

        % Value changed function: TrialnoEditField
        function TrialnoEditFieldValueChanged(app, event)
            value = app.TrialnoEditField.Value;
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Video is retrieved and displayed
            trial = app.TrialnoEditField.Value;
            fullpath = strcat(app.vid.foldername,app.slash,'Trial_',num2str(trial),'.mp4');
            app.vid.data = VideoReader(fullpath);
            app.vid.num_frames = app.vid.data.Duration * app.vid.data.FrameRate;
            app.vid.frame = 1; % points to the current index of the video
            app.vid.img = read(app.vid.data,1);
            imshow(app.vid.img,'parent',app.UIAxes);
            
            %% Next, the motion analysis metadata is retrieved
            slashes = strfind(app.vid.foldername,app.slash);
            
            app.meta = struct();
            app.meta = load(strcat(app.vid.foldername(1:slashes(end)),'motion_analysis.mat'));
            app.meta.UI = load(strcat(app.vid.foldername(1:slashes(end)),'UI_metadata.mat'));  
           
            % Now the motion analysis vectors are shown
            ma = app.MotionalgoDropDown.Value; % retrieve motion method from GUI
            % check it is valid:
            valid = 0;
            for algo = 1:length(app.meta.motion.algos)
                if strcmp(app.meta.motion.algos{algo},ma)
                    valid = 1;
                    ma_ind = algo;
                end
            end
            
            if valid
                hold(app.UIAxes_2); % Plot is released
                for roi = 1:size(app.meta.motion.Results,1) % For every ROI
                    % From the entire motion vector of that ROI, cut out the current trial
                    mot_all = squeeze(app.meta.motion.Results(roi,ma_ind,:));
                    mot_all_norm = (mot_all - min(mot_all)) / (max(mot_all) - min(mot_all));  % normalize to [0,1]
                    mot_trial = mot_all_norm(app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2));
                    
                    if roi == 1 
                        plot(1:length(mot_trial),mot_trial,'r', 'parent', app.UIAxes_2); hold(app.UIAxes_2); % Plot is hold
                    elseif roi == 2
                        plot(1:length(mot_trial),mot_trial+1.5,'g', 'parent', app.UIAxes_2); 
                    elseif roi == 3
                        plot(1:length(mot_trial),mot_trial+3,'b', 'parent', app.UIAxes_2); 
                    elseif roi ==4
                        plot(1:length(mot_trial),mot_trial+4.5,'m', 'parent', app.UIAxes_2);
                    end 
                end
                
                ax = app.UIAxes_2;
                % Define ylim 
                app.motion_plot.y_uplim = 1+(roi-1)*1.5;
                % Draw ylim and yticks
                eps = 0.1;
                ax.YLim = [0-eps app.motion_plot.y_uplim+eps];
                ax.YTick = 0:0.5:app.motion_plot.y_uplim; 
                
                % Define and draw yticklabels
                lab = 0:0.5:1;
                l = length(lab);
                for k = 1:roi-1
                    lab = [lab,lab(1:l)];
                end
                ax.YTickLabel = lab;  
                
                % Plot the black vertical bar that will be moving during playing the video
                app.motion_plot.timebar = line([app.vid.frame app.vid.frame], [0 app.motion_plot.y_uplim],'Color','black','parent', app.UIAxes_2);
            else
                fig = uifigure;
                uialert(fig,'Your specified motion method was not found. Please select another one','Retrieval error');
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
              
            global STOP;
            STOP = 0;

            % As long as video last frame has not been reached, show next frame
            while ~(app.vid.frame==app.vid.num_frames)
                
                app.vid.frame = app.vid.frame + 1;
                app.vid.img = read(app.vid.data,app.vid.frame);
                
                % Update the image plot
                if app.ShowROIsCheckBox.Value
                    app.showROI();
                else
                    imshow(app.vid.img,'parent',app.UIAxes);
                    drawnow;
                end
                
                % Update the motion vector plot
                delete(app.motion_plot.timebar)
                app.motion_plot.timebar = line([app.vid.frame app.vid.frame], [0 app.motion_plot.y_uplim],'Color','black','parent', app.UIAxes_2);
                
                % If the STOP button was pressed, interrupt
                if STOP 
                    break
                end
               
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        % Function executed when the stopp button is pressed
            global STOP
            STOP = 1;
            
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            % Restart button resets the video frame and shows the first frame in the video
            app.vid.frame = 1;
            app.vid.img = read(app.vid.data,app.vid.frame);
            if app.ShowROIsCheckBox.Value
                app.showROI();
            else
                imshow(app.vid.img,'parent',app.UIAxes);
                drawnow;
            end


            % Update the motion vector plot
            delete(app.motion_plot.timebar)
            app.motion_plot.timebar = line([app.vid.frame app.vid.frame], [0 app.motion_plot.y_uplim],'Color','black','parent', app.UIAxes_2);
                
            
        end

        % Value changed function: ShowROIsCheckBox
        function ShowROIsCheckBoxValueChanged(app, event)
            % Function is executed if show ROI checkbox is changed
            
           value = app.ShowROIsCheckBox.Value;
            
           % If ROIs should now be shown, open the showing function
           if value
               app.showROI();
           else
               % proceed one frame in video
                app.vid.frame = app.vid.frame + 1;
                app.vid.img = read(app.vid.data,app.vid.frame);
                imshow(app.vid.img,'parent',app.UIAxes);
                drawnow;
           end    
               
        end

        % Value changed function: ROI1EditField
        function ROI1EditFieldValueChanged(app, event)
            value = app.ROI1EditField.Value;
        end

        % Value changed function: ROI2EditField
        function ROI2EditFieldValueChanged(app, event)
            value = app.ROI2EditField.Value;
        end

        % Value changed function: ROI3EditField
        function ROI3EditFieldValueChanged(app, event)
            value = app.ROI3EditField.Value;
        end

        % Value changed function: ROI4EditField
        function ROI4EditFieldValueChanged(app, event)
            value = app.ROI4EditField.Value;
        end

        % Button pushed function: SaveallthresholdsButton
        function SaveallthresholdsButtonPushed(app, event)
            % This function is executed when all threshold are about to be saved
            
            
            trial = app.TrialnoEditField.Value;
            % Draw the thresholds into the graph
            for roi = 1:size(app.meta.motion.Results,1)
                if roi == 1
                    line([1 length(app.meta.motion.Results(1,1,app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2)))],...
                        [app.ROI1EditField.Value app.ROI1EditField.Value], 'Color','red','Linewidth', 2, 'parent', app.UIAxes_2);
                elseif roi == 2
                    line([1 length(app.meta.motion.Results(1,1,app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2)))],...
                        [app.ROI2EditField.Value+1.5 app.ROI2EditField.Value+1.5], 'Color','green','Linewidth', 2,'parent', app.UIAxes_2);
                elseif roi == 3
                    line([1 length(app.meta.motion.Results(1,1,app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2)))],...
                        [app.ROI3EditField.Value+3 app.ROI3EditField.Value+3],'Color','blue','Linewidth', 2,'parent', app.UIAxes_2);                
                elseif roi == 4
                    line([1 length(app.meta.motion.Results(1,1,app.meta.motion.bounds(trial,1):app.meta.motion.bounds(trial,2)))],...
                        [app.ROI4EditField.Value+4.5 app.ROI4EditField.Value+4.5],'Color','magenta','Linewidth', 2,'parent', app.UIAxes_2);
                end
            end
            
            % Ask user whether the choise is final
            fig = uifigure;
            message = ['Doublecheck your thresholds (shown as horizontal lines in the bottom figure). Confirming ', ...
                ' will binarize the vectors and save the results'];
            selection = uiconfirm(fig,message,'Confirm choice','Options',{'OK', 'Cancel'}, 'Defaultoption', 2, ...
            'Canceloption',2,'Icon','Warning');
            
            if strcmp(selection,'Cancel')
                close(fig)
            elseif strcmp(selection,'OK')
                % Now binarize the vectors and save them
                
                % Create struct and save other relevant variables
                results = struct;
                results.algo = app.MotionalgoDropDown.Value;
                results.ROIs = app.meta.motion.ROIs;
                results.thresholds = [app.ROI1EditField.Value app.ROI2EditField.Value app.ROI3EditField.Value app.ROI4EditField.Value];
                results.thresholds = results.thresholds(results.thresholds~=0); % exclude unused ROIs
                
                % Determin index of used motion algorithm
                for algo = 1:length(app.meta.motion.algos)
                    if strcmp(app.meta.motion.algos{algo},app.MotionalgoDropDown.Value)
                        ma_ind = algo;
                    end
                end
                
                % Binarization
                results.raw_motion = squeeze(app.meta.motion.Results(:,ma_ind,:));
                results.binary_motion = zeros(size(results.raw_motion));
                for roi = 1:size(results.binary_motion,1)
                    mot_all = results.raw_motion(roi,:); % 
                    mot_all_norm = (mot_all - min(mot_all)) / (max(mot_all) - min(mot_all));  % normalize to [0,1]
                    results.binary_motion(roi,:) = mot_all_norm > results.thresholds(roi) ;
                end

                slashes = strfind(app.vid.foldername,app.slash);
                save_path = strcat(app.vid.foldername(1:slashes(end)), app.slash, 'Motion_results_',results.algo);
                save(save_path, 'results')
                
                close(fig)
                
                fig = uifigure;
                uialert(fig,'The results have been saved successfully!','DONE!', ...
                    'Icon', 'success');
            end
        end

        % Value changed function: MotionalgoDropDown
        function MotionalgoDropDownValueChanged(app, event)
            value = app.MotionalgoDropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Video')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Motion vectors')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Norm. intensity')
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.TrialnoEditFieldLabel = uilabel(app.UIFigure);
            app.TrialnoEditFieldLabel.Text = 'Trial no.';
            app.TrialnoEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialnoEditField.ValueChangedFcn = createCallbackFcn(app, @TrialnoEditFieldValueChanged, true);
            app.TrialnoEditField.Value = 1;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.BinarizationthresholdsLabel = uilabel(app.UIFigure);
            app.BinarizationthresholdsLabel.Text = 'Binarization thresholds';
            app.ROI1EditFieldLabel = uilabel(app.UIFigure);
            app.ROI1EditFieldLabel.Text = 'ROI 1';
            app.ROI1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ROI1EditField.ValueChangedFcn = createCallbackFcn(app, @ROI1EditFieldValueChanged, true);
            app.ROI2EditFieldLabel = uilabel(app.UIFigure);
            app.ROI2EditFieldLabel.Text = 'ROI 2';
            app.ROI2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ROI2EditField.ValueChangedFcn = createCallbackFcn(app, @ROI2EditFieldValueChanged, true);
            app.ROI3EditFieldLabel = uilabel(app.UIFigure);
            app.ROI3EditFieldLabel.Text = 'ROI 3';
            app.ROI3EditField = uieditfield(app.UIFigure, 'numeric');
            app.ROI3EditField.ValueChangedFcn = createCallbackFcn(app, @ROI3EditFieldValueChanged, true);
            app.ROI4EditFieldLabel = uilabel(app.UIFigure);
            app.ROI4EditFieldLabel.Text = 'ROI 4';
            app.ROI4EditField = uieditfield(app.UIFigure, 'numeric');
            app.ROI4EditField.ValueChangedFcn = createCallbackFcn(app, @ROI4EditFieldValueChanged, true);
            app.SaveallthresholdsButton = uibutton(app.UIFigure, 'push');
            app.SaveallthresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallthresholdsButtonPushed, true);
            app.SaveallthresholdsButton.Text = 'Save all thresholds';
            app.ChoosefolderButton = uibutton(app.UIFigure, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Text = 'Choose folder';
            app.ShowROIsCheckBox = uicheckbox(app.UIFigure);
            app.ShowROIsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowROIsCheckBoxValueChanged, true);
            app.ShowROIsCheckBox.Text = 'Show ROIs';
            app.MotionalgoDropDownLabel = uilabel(app.UIFigure);
            app.MotionalgoDropDownLabel.Text = 'Motion algo';
            app.MotionalgoDropDown = uidropdown(app.UIFigure);
            app.MotionalgoDropDown.Items = {'Absolute difference', 'Pearson R', 'Lucas Kanade', 'Horn Schunck'};
            app.MotionalgoDropDown.ValueChangedFcn = createCallbackFcn(app, @MotionalgoDropDownValueChanged, true);
            app.MotionalgoDropDown.Value = 'Pearson R';
        end
    end

    methods (Access = public)

        % Construct app
        function app = motion_bin_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end